classdef searchApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TimeElapsedTextArea_2       matlab.ui.control.TextArea
        TimeElapsedTextArea_2Label  matlab.ui.control.Label
        StartSimulationButton       matlab.ui.control.Button
        TimeElapsedTextArea         matlab.ui.control.TextArea
        TimeElapsedTextAreaLabel    matlab.ui.control.Label
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: TimeElapsedTextArea
        function TimeElapsedTextAreaValueChanging(app, event)
            % changingValue = event.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

             %indx = app.UIAxes.Children(end).BrushData; %grab the indices
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            % this will begin the simulation for 10 iterations

            % will need to create 10 randomized locations for 2 rgvs
            % then UAS will not move until rectangular box is drawn
            % UAS will then move to do a search pattern on the bounds of
            % the rectangular box

            width = 10; % ft, will be based on FOV
            speed = 10; % ft/s, max speed
            autoPath = makePath([0,150],[0,150], width, speed);

            % for loop for 10 iterations until find randomized rgv
            % locations:
            
            [xBound,yBound] = selectDatapoints(app.UIAxes);
            assistedPath = makePath(xBound,yBound, width, speed);

            figure();
            scatter(assistedPath(1,:), assistedPath(2,:));
            xlim([0,150]);
            ylim([0,150]);
            figure();
            scatter(autoPath(1,:), autoPath(2,:));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Assisted Search')
            xlabel(app.UIAxes, 'X Position (ft)')
            ylabel(app.UIAxes, 'Y Position (ft)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 150];
            app.UIAxes.YLim = [0 150];
            app.UIAxes.XTickLabel = {'0'; '50'; '100'; '150'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Autonomous Search')
            xlabel(app.UIAxes_2, 'X Position (ft)')
            ylabel(app.UIAxes_2, 'Y Position (ft)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 150];
            app.UIAxes_2.YLim = [0 150];
            app.UIAxes_2.XTickLabel = {'0'; '50'; '100'; '150'};
            app.TimeElapsedTextAreaLabel = uilabel(app.UIFigure);
            app.TimeElapsedTextAreaLabel.Text = 'Time Elapsed';
            app.TimeElapsedTextArea = uitextarea(app.UIFigure);
            app.TimeElapsedTextArea.ValueChangingFcn = createCallbackFcn(app, @TimeElapsedTextAreaValueChanging, true);
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.TimeElapsedTextArea_2Label = uilabel(app.UIFigure);
            app.TimeElapsedTextArea_2Label.Text = 'Time Elapsed';
            app.TimeElapsedTextArea_2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = searchApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end